classdef Areasegmentappv3a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        FudgeFactorSliderLabel         matlab.ui.control.Label
        FudgeFactorSlider              matlab.ui.control.Slider
        MinSizeEditFieldLabel          matlab.ui.control.Label
        MinSizeEditField               matlab.ui.control.NumericEditField
        MaxSizeEditFieldLabel          matlab.ui.control.Label
        MaxSizeEditField               matlab.ui.control.NumericEditField
        FinishedButton                 matlab.ui.control.Button
        FrameSliderLabel               matlab.ui.control.Label
        FrameSlider                    matlab.ui.control.Slider
        SelectSampleDirectoryButton    matlab.ui.control.Button
        DoneCheckBox                   matlab.ui.control.CheckBox
        EdgeEliminationEditFieldLabel  matlab.ui.control.Label
        EdgeEliminationEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        sampleimage % Sample image file address
        fudgefactor % fudgeFactor
        mincellsize % minimum cell size
        maxcellsize % max cell size
        directory % path to folder containing images to mask
        numberFrames % Number of Frames
        currentFrame % The current frame the slider is on to mask
        images % cell array to hold the image locations to read dynamically. 
        EdgeElimnumber % amount of pixels to shrink the mask in by, 0 by default for none
        
    end
    

    methods (Access = private)

        % Value changed function: FudgeFactorSlider
        function FudgeFactorSliderValueChanged(app, event)
            
        end

        % Callback function
        function SelectSampleImageButtonPushed(app, event)
           %Get user inputed image 
           [baseName, folder] = uigetfile;
           fullfilename = fullfile(folder,baseName);
           app.sampleimage.Value = fullfilename;
        end

        % Value changing function: FudgeFactorSlider
        function FudgeFactorSliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.fudgefactor.Value = changingValue;
            %disp(app.fudgefactor.Value)            
            cellsize = [app.mincellsize.Value, app.maxcellsize.Value];
            %disp(cellsize)
            
            %disp(app.fudgefactor.Value)
            [maskedimage, ~] = appImageThresholdingv2(app.sampleimage.Value,app.fudgefactor.Value, cellsize,app.EdgeElimnumber.Value);
            imshow(maskedimage,'Parent',app.UIAxes);
        end

        % Value changed function: MinSizeEditField
        function MinSizeEditFieldValueChanged(app, event)
            value = app.MinSizeEditField.Value;
            app.mincellsize.Value = value;
        end

        % Value changed function: MaxSizeEditField
        function MaxSizeEditFieldValueChanged(app, event)
            value = app.MaxSizeEditField.Value;
            app.maxcellsize.Value = value;
        end

        % Callback function
        function SegmentButtonPushed(app, event)
            %Run code with provided settings from user inputs
            clear cellsize
            clear app.mincellsize.Value app.maxcellsize.Value app.sampleimage.Value app.fudgefactor.Value
            disp(app.mincellsize.Value)
            cellsize = [app.mincellsize.Value, app.maxcellsize.Value];
            display(cellsize)
            %disp(app.fudgefactor.Value)
            [maskedimage, ~] = appImageThresholdingv1(app.sampleimage.Value,app.fudgefactor.Value, cellsize);
            imshow(maskedimage,'Parent',app.UIAxes);
            
            
        end

        % Button pushed function: SelectSampleDirectoryButton
        function SelectSampleDirectoryButtonPushed(app, event)
            app.directory.Value = uigetdir;
            files=dir([app.directory.Value '/*.tif']);
            app.numberFrames.Value=size(files,1);
            %disp(app.numberFrames.Value)
            set(app.FrameSlider, 'Limits', [0 app.numberFrames.Value-1]);
            %disp('hi')
            %names = files(6).name;
            %disp(names)
            app.images.Value = cell(numel(app.numberFrames.Value),1);
            
            for i = 1:(app.numberFrames.Value)
                
                currentfilepath = strcat(files(i).folder, '\', files(i).name);
                
                app.images.Value{i} = currentfilepath;
                
            end
            
            %Pre initializing does not help this. 
            app.fudgefactor.Value = 0.5; % fudgeFactor
            app.mincellsize.Value = 1000; % minimum cell size
            app.maxcellsize.Value = 10000000; % max cell size
            app.currentFrame.Value = 0; % The current frame the slider is on to mask
            app.EdgeElimnumber.Value = 10; % amount of pixels to shrink the mask in by, 0 by default for none
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            %Round value to nearest whole number
            roundvalue = round(value);
            %disp(roundvalue)
            app.currentFrame.Value = roundvalue;
            %get current frame file path
            currentframepath = app.images.Value{roundvalue+1};
            %disp(currentframepath)
            
            %also set the global variable to be this frame
            app.sampleimage.Value = currentframepath;
            
            %now segment using the current settings
            clear cellsize
            clear app.mincellsize.Value app.maxcellsize.Value app.sampleimage.Value app.fudgefactor.Value app.EdgeElimnumber.Value
            %disp(app.mincellsize.Value)
            cellsize = [app.mincellsize.Value, app.maxcellsize.Value];
            %display(cellsize)
            %disp(app.fudgefactor.Value)
            [maskedimage, ~] = appImageThresholdingv2(currentframepath,app.fudgefactor.Value, cellsize,app.EdgeElimnumber.Value);
            imshow(maskedimage,'Parent',app.UIAxes);
            
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            %segment the image set and put into mask folder using the determined settings
            
            %set checkbox to value = 0 to let you know its not done
            app.DoneCheckBox.Value = 0;
            
            cellsize = [app.mincellsize.Value, app.maxcellsize.Value];
            
            CellMaskGeneratorFastv1app(app.directory.Value,app.fudgefactor.Value,cellsize,app.EdgeElimnumber.Value)
            
            %when done, set checkbox to = 1
            app.DoneCheckBox.Value = 1;
        end

        % Value changed function: EdgeEliminationEditField
        function EdgeEliminationEditFieldValueChanged(app, event)
            value = app.EdgeEliminationEditField.Value;
            app.EdgeElimnumber.Value = value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FudgeFactorSliderLabel = uilabel(app.UIFigure);
            app.FudgeFactorSliderLabel.Text = 'Fudge Factor';
            app.FudgeFactorSlider = uislider(app.UIFigure);
            app.FudgeFactorSlider.Limits = [0 1];
            app.FudgeFactorSlider.ValueChangedFcn = createCallbackFcn(app, @FudgeFactorSliderValueChanged, true);
            app.FudgeFactorSlider.ValueChangingFcn = createCallbackFcn(app, @FudgeFactorSliderValueChanging, true);
            app.MinSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MinSizeEditFieldLabel.Text = 'Min Size';
            app.MinSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinSizeEditFieldValueChanged, true);
            app.MaxSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MaxSizeEditFieldLabel.Text = 'Max Size';
            app.MaxSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxSizeEditFieldValueChanged, true);
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.SelectSampleDirectoryButton = uibutton(app.UIFigure, 'push');
            app.SelectSampleDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSampleDirectoryButtonPushed, true);
            app.SelectSampleDirectoryButton.Text = 'Select Sample Directory';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Text = 'Done';
            app.EdgeEliminationEditFieldLabel = uilabel(app.UIFigure);
            app.EdgeEliminationEditFieldLabel.Text = 'Edge Elimination';
            app.EdgeEliminationEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdgeEliminationEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeEliminationEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Areasegmentappv3a

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end