classdef MasterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GetDirectoryButton           matlab.ui.control.Button
        OrganizeMovieDetailsButton   matlab.ui.control.Button
        MoviesListBoxLabel           matlab.ui.control.Label
        MoviesListBox                matlab.ui.control.ListBox
        ProcessImagesButton          matlab.ui.control.Button
        ComputeTFmetricsButton       matlab.ui.control.Button
        MakeMasksButton              matlab.ui.control.Button
        TrackCellsButton             matlab.ui.control.Button
        CorrelationAnalysisButton_2  matlab.ui.control.Button
        PeriodicityAnalysisButton    matlab.ui.control.Button
        MakeMoviesButton             matlab.ui.control.Button
        SuperfineBearlab2020Label    matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        CheckBox                     matlab.ui.control.CheckBox
        CheckBox_2                   matlab.ui.control.CheckBox
        CheckBox_3                   matlab.ui.control.CheckBox
        CheckBox_4                   matlab.ui.control.CheckBox
        CheckBox_5                   matlab.ui.control.CheckBox
        CheckBox_6                   matlab.ui.control.CheckBox
        CheckBox_7                   matlab.ui.control.CheckBox
        CheckBox_8                   matlab.ui.control.CheckBox
        CheckBox_9                   matlab.ui.control.CheckBox
        ExitButton                   matlab.ui.control.Button
    end


    properties (Access = public)
        moviesDirectory % Directory for all Movies
    end


    methods (Access = private)

        % Button pushed function: GetDirectoryButton
        function GetDirectoryButtonPushed(app, event)
            app.moviesDirectory = uigetdir;
        end

        % Button pushed function: OrganizeMovieDetailsButton
        function OrganizeMovieDetailsButtonPushed(app, event)
            %When button is pushed, run script to loop through subdirectories and 
            %create a movie detail .mat file
            % Get a list of all files and folders in this folder.
            subfolders = GetSubDirsFirstLevelOnly(app.moviesDirectory);
            
            for i = 1:numel(subfolders)
               organizematdata(strcat(app.moviesDirectory,'\',subfolders{1,i})); 
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GetDirectoryButton = uibutton(app.UIFigure, 'push');
            app.GetDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetDirectoryButtonPushed, true);
            app.GetDirectoryButton.Text = 'Get Directory';
            app.OrganizeMovieDetailsButton = uibutton(app.UIFigure, 'push');
            app.OrganizeMovieDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @OrganizeMovieDetailsButtonPushed, true);
            app.OrganizeMovieDetailsButton.Text = 'Organize Movie Details';
            app.MoviesListBoxLabel = uilabel(app.UIFigure);
            app.MoviesListBoxLabel.Text = 'Movies';
            app.MoviesListBox = uilistbox(app.UIFigure);
            app.MoviesListBox.Items = {'Item 1'};
            app.ProcessImagesButton = uibutton(app.UIFigure, 'push');
            app.ProcessImagesButton.Text = 'Process Images';
            app.ComputeTFmetricsButton = uibutton(app.UIFigure, 'push');
            app.ComputeTFmetricsButton.Text = 'Compute TF metrics';
            app.MakeMasksButton = uibutton(app.UIFigure, 'push');
            app.MakeMasksButton.Text = 'Make Masks';
            app.TrackCellsButton = uibutton(app.UIFigure, 'push');
            app.TrackCellsButton.Text = 'Track Cells';
            app.CorrelationAnalysisButton_2 = uibutton(app.UIFigure, 'push');
            app.CorrelationAnalysisButton_2.Text = 'Correlation Analysis';
            app.PeriodicityAnalysisButton = uibutton(app.UIFigure, 'push');
            app.PeriodicityAnalysisButton.Text = 'Periodicity Analysis';
            app.MakeMoviesButton = uibutton(app.UIFigure, 'push');
            app.MakeMoviesButton.Text = 'Make Movies';
            app.SuperfineBearlab2020Label = uilabel(app.UIFigure);
            app.SuperfineBearlab2020Label.Text = 'Superfine/Bear lab 2020';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2.';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3.';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4.';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5.';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '6.';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '7.';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '8.';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.UIFigure);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.UIFigure);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.UIFigure);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.UIFigure);
            app.CheckBox_9.Text = '';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MasterGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end